classdef ThermalControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FluidHeatingSimulatorLabel   matlab.ui.control.Label
        SystemMonitoringPanel        matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        WaterTempratureCGauge        matlab.ui.control.Gauge
        WaterTempratureCGaugeLabel   matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        SimulationControlPanel       matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        OperatorControlsPanel        matlab.ui.container.Panel
        InsulationSwitch             matlab.ui.control.ToggleSwitch
        InsulationSwitchLabel        matlab.ui.control.Label
        InletTempCEditField          matlab.ui.control.NumericEditField
        InletTempCEditFieldLabel     matlab.ui.control.Label
        StatusIndicatorLamp          matlab.ui.control.Lamp
        StatusIndicatorLampLabel     matlab.ui.control.Label
        HeaterSwitch                 matlab.ui.control.ToggleSwitch
        HeaterSwitchLabel            matlab.ui.control.Label
        FlowRatekgsKnob              matlab.ui.control.Knob
        FlowRatekgsKnobLabel         matlab.ui.control.Label
        HeaterPowerWKnob             matlab.ui.control.Knob
        HeaterPowerWKnobLabel        matlab.ui.control.Label
        FluidTypeDropDown            matlab.ui.control.DropDown
        FluidTypeDropDownLabel       matlab.ui.control.Label
        SystemSetupPanel             matlab.ui.container.Panel
        PipeLengthmEditField         matlab.ui.control.NumericEditField
        PipeLengthmEditFieldLabel    matlab.ui.control.Label
        PipeDiametermEditField       matlab.ui.control.NumericEditField
        PipeDiametermEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function R(app, event)
            
        end

        % Button pushed function: RunButton
        function RunSimulationPushButton(app, event)

    pipe_diameter = app.PipeDiametermEditField.Value;
    pipe_length = app.PipeLengthmEditField.Value;
    fluid_type = app.FluidTypeDropDown.Value;
    flow_rate = app.FlowRatekgsKnob.Value;
    T_in = app.InletTempCEditField.Value;
    heaterOn = app.HeaterSwitch.Value;
    insulationOn = app.InsulationSwitch.Value;


    if strcmp(heaterOn, 'On')
        app.StatusIndicatorLamp.Color = [0 1 0];
        heater_power = app.HeaterPowerWKnob.Value;
    else
        app.StatusIndicatorLamp.Color = [1 0 0];
        heater_power = 0;
    end

        if strcmp(insulationOn, 'On')
            h_loss = 5; %W/m²·K
        else
            h_loss = 25;

        end

    [t, T, efficiency] = simulateHeating(pipe_diameter, pipe_length,...
        fluid_type, heater_power, flow_rate, T_in, h_loss);

    % Plot temperature
    plot(app.UIAxes, t, T, 'b-', 'LineWidth', 2);
    grid(app.UIAxes, 'on');

    % Plot efficiency
    plot(app.UIAxes2, t, efficiency, 'r--', 'LineWidth', 2);
    ylim(app.UIAxes2, [0 100]);
    grid(app.UIAxes2, 'on');


    app.WaterTempratureCGauge.Value = T(end);
    app.Label.Text = num2str(T(end),'%.1f');


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    app.FluidTypeDropDown.Value = 'Water';        
    app.FlowRatekgsKnob.Value = 0;             
    app.InletTempCEditField.Value = 0;            
    app.HeaterSwitch.Value = 'Off';           
    app.HeaterPowerWKnob.Value = 0;               

    app.StatusIndicatorLamp.Color = [1 0 0];

    cla(app.UIAxes);
    cla(app.UIAxes2);

    app.WaterTempratureCGauge.Value = 0;
    app.Label.Text = '0';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SystemSetupPanel = uipanel(app.UIFigure);
            app.SystemSetupPanel.Title = 'System Setup';
            app.PipeDiametermEditFieldLabel = uilabel(app.SystemSetupPanel);
            app.PipeDiametermEditFieldLabel.Text = 'Pipe Diameter (m)';
            app.PipeDiametermEditField = uieditfield(app.SystemSetupPanel, 'numeric');
            app.PipeDiametermEditField.Editable = 'off';
            app.PipeDiametermEditField.Value = 0.025;
            app.PipeLengthmEditFieldLabel = uilabel(app.SystemSetupPanel);
            app.PipeLengthmEditFieldLabel.Text = 'Pipe Length (m)';
            app.PipeLengthmEditField = uieditfield(app.SystemSetupPanel, 'numeric');
            app.PipeLengthmEditField.Editable = 'off';
            app.PipeLengthmEditField.Value = 5;
            app.OperatorControlsPanel = uipanel(app.UIFigure);
            app.OperatorControlsPanel.Title = 'Operator Controls';
            app.FluidTypeDropDownLabel = uilabel(app.OperatorControlsPanel);
            app.FluidTypeDropDownLabel.Text = 'Fluid Type';
            app.FluidTypeDropDown = uidropdown(app.OperatorControlsPanel);
            app.FluidTypeDropDown.Items = {'Water', 'Oil', 'Alcohol', 'Mercury'};
            app.FluidTypeDropDown.Value = 'Water';
            app.HeaterPowerWKnobLabel = uilabel(app.OperatorControlsPanel);
            app.HeaterPowerWKnobLabel.Text = 'Heater Power (W)';
            app.HeaterPowerWKnob = uiknob(app.OperatorControlsPanel, 'continuous');
            app.HeaterPowerWKnob.Limits = [0 2000];
            app.FlowRatekgsKnobLabel = uilabel(app.OperatorControlsPanel);
            app.FlowRatekgsKnobLabel.Text = 'Flow Rate (kg/s)';
            app.FlowRatekgsKnob = uiknob(app.OperatorControlsPanel, 'continuous');
            app.FlowRatekgsKnob.Limits = [0 0.025];
            app.HeaterSwitchLabel = uilabel(app.OperatorControlsPanel);
            app.HeaterSwitchLabel.Text = 'Heater Switch';
            app.HeaterSwitch = uiswitch(app.OperatorControlsPanel, 'toggle');
            app.StatusIndicatorLampLabel = uilabel(app.OperatorControlsPanel);
            app.StatusIndicatorLampLabel.Text = 'Status Indicator';
            app.StatusIndicatorLamp = uilamp(app.OperatorControlsPanel);
            app.InletTempCEditFieldLabel = uilabel(app.OperatorControlsPanel);
            app.InletTempCEditFieldLabel.Text = 'Inlet Temp (°C)';
            app.InletTempCEditField = uieditfield(app.OperatorControlsPanel, 'numeric');
            app.InletTempCEditField.Value = 25;
            app.InsulationSwitchLabel = uilabel(app.OperatorControlsPanel);
            app.InsulationSwitchLabel.Text = 'Insulation';
            app.InsulationSwitch = uiswitch(app.OperatorControlsPanel, 'toggle');
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.RunButton = uibutton(app.SimulationControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationPushButton, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.SimulationControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SystemMonitoringPanel = uipanel(app.UIFigure);
            app.SystemMonitoringPanel.Title = 'System Monitoring';
            app.UIAxes = uiaxes(app.SystemMonitoringPanel);
            title(app.UIAxes, 'Fluid Temperature vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temprature (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.SystemMonitoringPanel);
            title(app.UIAxes2, 'Heating Efficiency vs Time')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Efficiency (%)')
            zlabel(app.UIAxes2, 'Z')
            app.WaterTempratureCGaugeLabel = uilabel(app.SystemMonitoringPanel);
            app.WaterTempratureCGaugeLabel.Text = 'Water Temprature (°C)';
            app.WaterTempratureCGauge = uigauge(app.SystemMonitoringPanel, 'circular');
            app.Label = uilabel(app.SystemMonitoringPanel);
            app.Label.Text = '0';
            app.FluidHeatingSimulatorLabel = uilabel(app.UIFigure);
            app.FluidHeatingSimulatorLabel.Text = 'Fluid Heating Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThermalControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end